classdef FreeSpaceOpticalCommunicationsApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FSOCSystemUIFigure             matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        InputTab                       matlab.ui.container.Tab
        AverageTransmittedPower        matlab.ui.control.NumericEditField
        TransmitedPowermWLabel         matlab.ui.control.Label
        CalculateButton                matlab.ui.control.Button
        Modulation                     matlab.ui.control.DropDown
        ModulationDropDownLabel        matlab.ui.control.Label
        AtmosphericConditions          matlab.ui.control.DropDown
        AtmosphericConditionsLabel     matlab.ui.control.Label
        ThetaUnits                     matlab.ui.control.DropDown
        Theta                          matlab.ui.control.NumericEditField
        BeamDivergenceLabel            matlab.ui.control.Label
        Apperture                      matlab.ui.control.EditField
        ApperturecmLabel               matlab.ui.control.Label
        BitRateUnits                   matlab.ui.control.DropDown
        BitRate                        matlab.ui.control.NumericEditField
        RequiredbitrateEditFieldLabel  matlab.ui.control.Label
        LinkLengthkm                   matlab.ui.control.NumericEditField
        LinkLengthkmEditFieldLabel     matlab.ui.control.Label
        Wavelength                     matlab.ui.control.NumericEditField
        WavelengthnmEditFieldLabel     matlab.ui.control.Label
        WhereDropDown                  matlab.ui.control.DropDown
        WhereDropDownLabel             matlab.ui.control.Label
        TransmitterLabel               matlab.ui.control.Label
        TransmittedMessage             matlab.ui.control.EditField
        EntermessagetobetransmittedLabel  matlab.ui.control.Label
        TransmitterReceiver            matlab.ui.container.TabGroup
        Transmitter2Receiver           matlab.ui.container.Tab
        YAxismisalignmentdegreesSlider_default  matlab.ui.control.Slider
        XAxismisalignmentdegreesSlider_default  matlab.ui.control.Slider
        Transmitter2ReceiverPlot       matlab.ui.control.UIAxes
        two_stage_transmission         matlab.ui.container.TabGroup
        Transmitter2satTab             matlab.ui.container.Tab
        YAxismisalignmentdegreesSlider_1st_stage  matlab.ui.control.Slider
        XAxismisalignmentdegreesSlider_1st_stage  matlab.ui.control.Slider
        Transmitter2SatPlot            matlab.ui.control.UIAxes
        Sat2receiverTab                matlab.ui.container.Tab
        YAxismisalignmentdegreesSlider_2nd_stage  matlab.ui.control.Slider
        XAxismisalignmentdegreesSlider_2nd_stage  matlab.ui.control.Slider
        Sat2ReceiverPlot               matlab.ui.control.UIAxes
        OutputTab                      matlab.ui.container.Tab
        TabGroup3                      matlab.ui.container.TabGroup
        BERvsSNRTab                    matlab.ui.container.Tab
        BERvsSNR                       matlab.ui.control.UIAxes
        BERvsQTab                      matlab.ui.container.Tab
        BERvsQ                         matlab.ui.control.UIAxes
        CapacityvsBandwidthTab         matlab.ui.container.Tab
        CAPACITYvsSNR                  matlab.ui.control.UIAxes
        BacktotransmitterButton        matlab.ui.control.Button
        QualityFactor                  matlab.ui.control.NumericEditField
        SNREditFieldLabel_2            matlab.ui.control.Label
        SNR                            matlab.ui.control.NumericEditField
        SNREditFieldLabel              matlab.ui.control.Label
        BER                            matlab.ui.control.NumericEditField
        BEREditFieldLabel              matlab.ui.control.Label
        Receivedback                   matlab.ui.control.EditField
        ReceivedbackEditFieldLabel     matlab.ui.control.Label
        ReceiverLabel                  matlab.ui.control.Label
        PointingErrorsdB               matlab.ui.control.Label
        PointingErrors                 matlab.ui.control.NumericEditField
        PointingErrorsLabel            matlab.ui.control.Label
        TurbulanceEffectdB             matlab.ui.control.Label
        TurbulanceEffect               matlab.ui.control.NumericEditField
        TurbulanceEffectLabel          matlab.ui.control.Label
        GeometricalLossesdB            matlab.ui.control.Label
        GeometricalLosses              matlab.ui.control.NumericEditField
        GeometricalLossesLabel         matlab.ui.control.Label
        ScintillationdB                matlab.ui.control.Label
        Scintillation                  matlab.ui.control.NumericEditField
        ScintillationLabel             matlab.ui.control.Label
        AtmosphericAttenuationdB       matlab.ui.control.Label
        AtmosphericAttenuation         matlab.ui.control.NumericEditField
        AtmosphericAttenuationEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        % transmitted % Description
    end
    methods (Access = public)
        function plotter(app,received_binary,sent_binary,BW, ...
                mc_bit_error_rate,snr,modulation)
            [~,measured_bit_error_rate]=biterr(sent_binary,received_binary);
            measured_bit_error_rate= measured_bit_error_rate*ones(1,length(snr));
            % snrdb=1:9/length(received_binary):10-(9/length(received_binary));
            snrlin=10.^(snr./10);
            % mc_bit_error_rate=0.5.*erfc(sqrt(snrlin));
            % -------------------------------------------------------------
            % BER vs SNR
            semilogy(app.BERvsSNR,snr,measured_bit_error_rate,'-bo',snr,mc_bit_error_rate,'-mh');
            legend(app.BERvsSNR,"measured","thoretical");
            title_text= [modulation, ' modulation with AWGN for SNR: ' ,num2str(min(snr)),' to ',num2str(max(snr)), ' dB'];
            title(app.BERvsSNR, title_text);
            xlabel(app.BERvsSNR,'Eb/No - SNR (dB)');
            ylabel(app.BERvsSNR,'Bit error rate');
            % -------------------------------------------------------------
            % BER vs Q
            quality_factor= sqrt(2)*erfcinv(2*measured_bit_error_rate);
            MC_quality_factor=sqrt(snr)/2;
            semilogy(app.BERvsQ, quality_factor,measured_bit_error_rate,'-bo',...
                MC_quality_factor,mc_bit_error_rate,'-mh');
            title_text= ['Quality factor plot for ',modulation,' modulation with AWGN for SNR: ',num2str(min(snr)), ' to ',num2str(max(snr)),' dB'];
            title(app.BERvsQ,title_text);
            xlabel(app.BERvsQ,'Quality Factor (Q)');
            ylabel(app.BERvsQ,'Bit error rate (BER)');
            legend(app.BERvsQ, "measured","theoretical");
            % -------------------------------------------------------------
            % Capacity of the system
            capacity=2*BW*log2(1+snr); % varying power - not BR
            % capacity=capacity/1e9;
            semilogy(app.CAPACITYvsSNR,snr,capacity);
            title_text= ['Capacity of the system for ',modulation,' modulation for variable power'];
            title(app.CAPACITYvsSNR,title_text);
            xlabel(app.CAPACITYvsSNR,'Eb/No - SNR (dB)');
            ylabel(app.CAPACITYvsSNR,'Capacity (C) in Gbits/second');
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % Inputs
            % fig = uifigure;
            d = uiprogressdlg(app.FSOCSystemUIFigure,'Title','Simulating...',...
                'Message','Loading your values....');
            pause(.5)
           
            text = app.TransmittedMessage.Value;
            wavelength = app.Wavelength.Value*1e-9;
            modulation = app.Modulation.Value;
            transmission_location = app.WhereDropDown.Value;
            link_length=app.LinkLengthkm.Value;
            BR=app.BitRate.Value;
            switch transmission_location
                case "Ground only"
                    x1=app.XAxismisalignmentdegreesSlider_default.Value;
                    y1=app.YAxismisalignmentdegreesSlider_default.Value;
                    x2=0;
                    y2=0;
                case "Cosmic Space only"
                    x1=app.XAxismisalignmentdegreesSlider_default.Value;
                    y1=app.YAxismisalignmentdegreesSlider_default.Value;
                    x2=0;
                    y2=0;
                otherwise % Ground via sat relay || Cosmic Space from earth
                    x1=app.XAxismisalignmentdegreesSlider_1st_stage.Value;
                    y1=app.YAxismisalignmentdegreesSlider_1st_stage.Value;
                    x2=app.XAxismisalignmentdegreesSlider_2nd_stage.Value;
                    y2=app.YAxismisalignmentdegreesSlider_2nd_stage.Value;
            end
            misaligment=[x1,y1,x2,y2];
            switch app.BitRateUnits.Value
                case "kbps"
                    BR=1e3*BR;
                case "Mbps"
                    BR=1e6*BR;
                case "Gbps"
                    BR=1e9*BR;
                case "Tbps"
                    BR=1e12*BR;
            end
            apperture=str2double(app.Apperture.Value);                     % made a lil oopsie by inserting a text field
            beam_divergence=app.Theta.Value;
            if app.ThetaUnits.Value=="rad"
                beam_divergence=beam_divergence*180/pi;
            end
            atm_conditions= app.AtmosphericConditions.Value;
            av_transmitted_power= app.AverageTransmittedPower.Value;
            d.Value = .33; 
            d.Message = 'Calculating....';
            pause(1)
            [text_output,ber,snr,total_losses,received_binary,sent_binary,BW] = fso_simulation(text, wavelength, modulation,transmission_location,link_length,BR,apperture,beam_divergence,atm_conditions,misaligment,av_transmitted_power);            %
            
            % Outputs
            app.Receivedback.Value=text_output;
            app.BER.Value=ber;
            app.SNR.Value=snr;
            if snr<0
                app.QualityFactor.Value=0;
            else
                app.QualityFactor.Value=sqrt(snr)/2;
            end
            app.GeometricalLosses.Value=total_losses(3);
            app.AtmosphericAttenuation.Value=total_losses(1);
            app.Scintillation.Value=total_losses(2);

            d.Value = .67;
            d.Message = 'Performing MC....';
            pause(1)

            % Plotting
            [bit_error_rate,snr] = monte_carlo(text,transmission_location,apperture,...
            beam_divergence, link_length,misaligment,...
            atm_conditions,wavelength,BW,modulation,BR,av_transmitted_power);

            plotter(app,received_binary,sent_binary,BW,bit_error_rate,snr,modulation);

            d.Value = 0.9;
            d.Message = 'Writing values and plotting....';
            pause(1)
            app.TabGroup.SelectedTab = app.OutputTab;
            d.Value=1;
            d.Message = 'Finishing up....';
            pause(1)
            close(d)
        end

        % Value changed function: WhereDropDown
        function WhereDropDownValueChanged(app, event)
            % Visibility toggle
            switch app.WhereDropDown.Value 
                case "Ground via sat relay"
                    app.LinkLengthkm.Visible ="off";
                    app.AtmosphericConditions.Visible="on";
                    app.AtmosphericConditionsLabel.Visible="on";
                    app.LinkLengthkmEditFieldLabel.Visible = "off";
                    app.TransmitterReceiver.Visible="off";
                    app.two_stage_transmission.Visible="on";
                case "Cosmic Space from earth"
                    app.LinkLengthkm.Visible="on";
                    app.AtmosphericConditions.Visible="on";
                    app.AtmosphericConditionsLabel.Visible="on";
                    app.LinkLengthkmEditFieldLabel.Visible = "on";
                    app.TransmitterReceiver.Visible="off";
                    app.two_stage_transmission.Visible="on";
                case "Cosmic Space only"
                    app.LinkLengthkm.Visible="on";
                    app.AtmosphericConditions.Visible="off";
                    app.AtmosphericConditionsLabel.Visible="off";
                    app.AtmosphericConditionsLabel.Visible="off";
                    app.LinkLengthkmEditFieldLabel.Visible = "on";
                    app.TransmitterReceiver.Visible="on";
                    app.two_stage_transmission.Visible="off";
                otherwise % ground only
                    app.LinkLengthkm.Visible="on";                    
                    app.AtmosphericConditions.Visible="on";
                    app.AtmosphericConditionsLabel.Visible="on";
                    app.LinkLengthkmEditFieldLabel.Visible = "on";
                    app.TransmitterReceiver.Visible="on";
                    app.two_stage_transmission.Visible="off";
            end
        end

        % Button pushed function: BacktotransmitterButton
        function BacktotransmitterButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.InputTab;
        end

        % Value changed function: XAxismisalignmentdegreesSlider_default, 
        % ...and 1 other component
        function YAxismisalignmentdegreesSlider_defaultValueChanged(app, event)
            changed_Value_x_axis = app.XAxismisalignmentdegreesSlider_default.Value;
            changed_Value_y_axis = app.YAxismisalignmentdegreesSlider_default.Value;
            plot(app.Transmitter2ReceiverPlot,0,0,'c*', ...
                (sin(changed_Value_x_axis*pi/180)*app.LinkLengthkm.Value), ...
                (sin(changed_Value_y_axis*pi/180)*app.LinkLengthkm.Value),'r*');
            ylim(app.Transmitter2ReceiverPlot,[-5,5]);
            xlim(app.Transmitter2ReceiverPlot,[-5,5]);
            legend(app.Transmitter2ReceiverPlot,"Receiving","Transmitting");
        end

        % Value changed function: 
        % XAxismisalignmentdegreesSlider_1st_stage, 
        % ...and 1 other component
        function YAxismisalignmentdegreesSlider_1st_stageValueChanged(app, event)
            changed_Value_x_axis = app.XAxismisalignmentdegreesSlider_1st_stage.Value;
            changed_Value_y_axis = app.YAxismisalignmentdegreesSlider_1st_stage.Value;
            plot(app.Transmitter2SatPlot,0,0,'c*', ...
                (sin(changed_Value_x_axis*pi/180)*app.LinkLengthkm.Value), ...
                (sin(changed_Value_y_axis*pi/180)*app.LinkLengthkm.Value),'r*');
            ylim(app.Transmitter2SatPlot,[-5,5]);
            xlim(app.Transmitter2SatPlot,[-5,5]);
            legend(app.Transmitter2SatPlot,"Receiving","Transmitting");
        end

        % Value changed function: 
        % XAxismisalignmentdegreesSlider_2nd_stage, 
        % ...and 1 other component
        function YAxismisalignmentdegreesSlider_2nd_stageValueChanged(app, event)
            changed_Value_x_axis = app.XAxismisalignmentdegreesSlider_2nd_stage.Value;
            changed_Value_y_axis = app.YAxismisalignmentdegreesSlider_2nd_stage.Value;
            plot(app.Sat2ReceiverPlot,0,0,'c*', ...
                (sin(changed_Value_x_axis*pi/180)*app.LinkLengthkm.Value), ...
                (sin(changed_Value_y_axis*pi/180)*app.LinkLengthkm.Value),'r*');
            ylim(app.Sat2ReceiverPlot,[-5,5]);
            xlim(app.Sat2ReceiverPlot,[-5,5]);
            legend(app.Sat2ReceiverPlot,"Receiving","Transmitting");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.FSOCSystemUIFigure = uifigure('Visible', 'off');
            app.FSOCSystemUIFigure.Name = 'FSOC System';
            app.FSOCSystemUIFigure.Icon = fullfile(pathToMLAPP, 'laser.png');
            app.TabGroup = uitabgroup(app.FSOCSystemUIFigure);
            app.InputTab = uitab(app.TabGroup);
            app.InputTab.Title = 'Input';
            app.two_stage_transmission = uitabgroup(app.InputTab);
            app.Transmitter2satTab = uitab(app.two_stage_transmission);
            app.Transmitter2satTab.Title = 'Transmitter to sat';
            app.Transmitter2SatPlot = uiaxes(app.Transmitter2satTab);
            title(app.Transmitter2SatPlot, '1st stage misalignment (in km)')
            xlabel(app.Transmitter2SatPlot, 'X Axis misalignment (degrees)')
            ylabel(app.Transmitter2SatPlot, 'X Axis misalignment (degrees)')
            zlabel(app.Transmitter2SatPlot, 'Z')
            app.XAxismisalignmentdegreesSlider_1st_stage = uislider(app.Transmitter2satTab);
            app.XAxismisalignmentdegreesSlider_1st_stage.Limits = [-60 60];
            app.XAxismisalignmentdegreesSlider_1st_stage.MajorTicks = [-60 -45 -30 -15 0 15 30 45 60];
            app.XAxismisalignmentdegreesSlider_1st_stage.MajorTickLabels = {'-60', '-45', '-30', '-15', '0', '15', '30', '45', '60'};
            app.XAxismisalignmentdegreesSlider_1st_stage.ValueChangedFcn = createCallbackFcn(app, @YAxismisalignmentdegreesSlider_1st_stageValueChanged, true);
            app.YAxismisalignmentdegreesSlider_1st_stage = uislider(app.Transmitter2satTab);
            app.YAxismisalignmentdegreesSlider_1st_stage.Limits = [-60 60];
            app.YAxismisalignmentdegreesSlider_1st_stage.MajorTicks = [-60 -45 -30 -15 0 15 30 45 60];
            app.YAxismisalignmentdegreesSlider_1st_stage.MajorTickLabels = {'-60', '-45', '-30', '-15', '0', '15', '30', '45', '60'};
            app.YAxismisalignmentdegreesSlider_1st_stage.ValueChangedFcn = createCallbackFcn(app, @YAxismisalignmentdegreesSlider_1st_stageValueChanged, true);
            app.Sat2receiverTab = uitab(app.two_stage_transmission);
            app.Sat2receiverTab.Title = 'Sat to receiver';
            app.Sat2ReceiverPlot = uiaxes(app.Sat2receiverTab);
            title(app.Sat2ReceiverPlot, '2nd stage misalignment (in km)')
            xlabel(app.Sat2ReceiverPlot, 'X Axis misalignment (degrees)')
            ylabel(app.Sat2ReceiverPlot, 'Y Axis misalignment (degrees)')
            zlabel(app.Sat2ReceiverPlot, 'Z')
            app.XAxismisalignmentdegreesSlider_2nd_stage = uislider(app.Sat2receiverTab);
            app.XAxismisalignmentdegreesSlider_2nd_stage.Limits = [-60 60];
            app.XAxismisalignmentdegreesSlider_2nd_stage.MajorTicks = [-60 -45 -30 -15 0 15 30 45 60];
            app.XAxismisalignmentdegreesSlider_2nd_stage.ValueChangedFcn = createCallbackFcn(app, @YAxismisalignmentdegreesSlider_2nd_stageValueChanged, true);
            app.YAxismisalignmentdegreesSlider_2nd_stage = uislider(app.Sat2receiverTab);
            app.YAxismisalignmentdegreesSlider_2nd_stage.Limits = [-60 60];
            app.YAxismisalignmentdegreesSlider_2nd_stage.MajorTicks = [-60 -45 -30 -15 0 15 30 45 60];
            app.YAxismisalignmentdegreesSlider_2nd_stage.ValueChangedFcn = createCallbackFcn(app, @YAxismisalignmentdegreesSlider_2nd_stageValueChanged, true);
            app.TransmitterReceiver = uitabgroup(app.InputTab);
            app.Transmitter2Receiver = uitab(app.TransmitterReceiver);
            app.Transmitter2Receiver.Title = 'Transmitter to receiver';
            app.Transmitter2ReceiverPlot = uiaxes(app.Transmitter2Receiver);
            title(app.Transmitter2ReceiverPlot, 'Transmission misalignent (in km)')
            xlabel(app.Transmitter2ReceiverPlot, 'X Axis misalignment (degrees)')
            ylabel(app.Transmitter2ReceiverPlot, 'Y Axis misalignment (degrees)')
            zlabel(app.Transmitter2ReceiverPlot, 'Z')
            app.XAxismisalignmentdegreesSlider_default = uislider(app.Transmitter2Receiver);
            app.XAxismisalignmentdegreesSlider_default.Limits = [-60 60];
            app.XAxismisalignmentdegreesSlider_default.MajorTicks = [-60 -45 -30 -15 0 15 30 45 60];
            app.XAxismisalignmentdegreesSlider_default.ValueChangedFcn = createCallbackFcn(app, @YAxismisalignmentdegreesSlider_defaultValueChanged, true);
            app.YAxismisalignmentdegreesSlider_default = uislider(app.Transmitter2Receiver);
            app.YAxismisalignmentdegreesSlider_default.Limits = [-60 60];
            app.YAxismisalignmentdegreesSlider_default.MajorTicks = [-60 -45 -30 -15 0 15 30 45 60];
            app.YAxismisalignmentdegreesSlider_default.ValueChangedFcn = createCallbackFcn(app, @YAxismisalignmentdegreesSlider_defaultValueChanged, true);
            app.EntermessagetobetransmittedLabel = uilabel(app.InputTab);
            app.EntermessagetobetransmittedLabel.Text = {'Enter message'; 'to be transmitted:'};
            app.TransmittedMessage = uieditfield(app.InputTab, 'text');
            app.TransmittedMessage.Value = 'Never gonna give you up, never gonna let you down. Never gonna run around and desert you. Never gonna make you cry, never gonna say goodbye. Never gonna tell a lie and hurt you';
            app.TransmitterLabel = uilabel(app.InputTab);
            app.TransmitterLabel.Text = 'Transmitter';
            app.WhereDropDownLabel = uilabel(app.InputTab);
            app.WhereDropDownLabel.Text = 'Where?';
            app.WhereDropDown = uidropdown(app.InputTab);
            app.WhereDropDown.Items = {'Ground only', 'Ground via sat relay', 'Cosmic Space from earth', 'Cosmic Space only'};
            app.WhereDropDown.ValueChangedFcn = createCallbackFcn(app, @WhereDropDownValueChanged, true);
            app.WhereDropDown.Value = 'Ground only';
            app.WavelengthnmEditFieldLabel = uilabel(app.InputTab);
            app.WavelengthnmEditFieldLabel.Text = 'Wavelength (nm)';
            app.Wavelength = uieditfield(app.InputTab, 'numeric');
            app.Wavelength.Value = 1550;
            app.LinkLengthkmEditFieldLabel = uilabel(app.InputTab);
            app.LinkLengthkmEditFieldLabel.Text = 'Link Length (km)';
            app.LinkLengthkm = uieditfield(app.InputTab, 'numeric');
            app.LinkLengthkm.Value = 20;
            app.RequiredbitrateEditFieldLabel = uilabel(app.InputTab);
            app.RequiredbitrateEditFieldLabel.Text = 'Required bit rate';
            app.BitRate = uieditfield(app.InputTab, 'numeric');
            app.BitRate.Value = 200;
            app.BitRateUnits = uidropdown(app.InputTab);
            app.BitRateUnits.Items = {'kbps', 'Mbps', 'Gbps', 'Tbps'};
            app.BitRateUnits.Value = 'kbps';
            app.ApperturecmLabel = uilabel(app.InputTab);
            app.ApperturecmLabel.Text = 'Apperture (m)';
            app.Apperture = uieditfield(app.InputTab, 'text');
            app.Apperture.Value = '0.2';
            app.BeamDivergenceLabel = uilabel(app.InputTab);
            app.BeamDivergenceLabel.Text = 'Beam Divergence';
            app.Theta = uieditfield(app.InputTab, 'numeric');
            app.Theta.Value = 0.002;
            app.ThetaUnits = uidropdown(app.InputTab);
            app.ThetaUnits.Items = {'rad', 'degrees'};
            app.ThetaUnits.Value = 'rad';
            app.AtmosphericConditionsLabel = uilabel(app.InputTab);
            app.AtmosphericConditionsLabel.Text = {'Atmospheric'; 'Conditions'};
            app.AtmosphericConditions = uidropdown(app.InputTab);
            app.AtmosphericConditions.Items = {'Very clear air', 'Clear air', 'Very light mist', 'Light mist', 'Very light fog', 'Light fog', 'Moderate fog', 'Thick fog', 'Dense fog'};
            app.AtmosphericConditions.Value = 'Very clear air';
            app.ModulationDropDownLabel = uilabel(app.InputTab);
            app.ModulationDropDownLabel.Text = 'Modulation ';
            app.Modulation = uidropdown(app.InputTab);
            app.Modulation.Items = {'OOK', 'QPSK', '16 QAM', 'No modulation'};
            app.Modulation.Value = 'OOK';
            app.CalculateButton = uibutton(app.InputTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate!';
            app.TransmitedPowermWLabel = uilabel(app.InputTab);
            app.TransmitedPowermWLabel.Text = {'Average Transmited '; 'Power (W)'};
            app.AverageTransmittedPower = uieditfield(app.InputTab, 'numeric');
            app.AverageTransmittedPower.Value = 5;
            app.OutputTab = uitab(app.TabGroup);
            app.OutputTab.Title = 'Output';
            app.AtmosphericAttenuationEditFieldLabel = uilabel(app.OutputTab);
            app.AtmosphericAttenuationEditFieldLabel.Text = 'Scattering';
            app.AtmosphericAttenuation = uieditfield(app.OutputTab, 'numeric');
            app.AtmosphericAttenuationdB = uilabel(app.OutputTab);
            app.AtmosphericAttenuationdB.Text = 'dB';
            app.ScintillationLabel = uilabel(app.OutputTab);
            app.ScintillationLabel.Text = 'Scintillation';
            app.Scintillation = uieditfield(app.OutputTab, 'numeric');
            app.ScintillationdB = uilabel(app.OutputTab);
            app.ScintillationdB.Text = 'dB';
            app.GeometricalLossesLabel = uilabel(app.OutputTab);
            app.GeometricalLossesLabel.Text = 'Geometrical Losses';
            app.GeometricalLosses = uieditfield(app.OutputTab, 'numeric');
            app.GeometricalLossesdB = uilabel(app.OutputTab);
            app.GeometricalLossesdB.Text = 'dB';
            app.TurbulanceEffectLabel = uilabel(app.OutputTab);
            app.TurbulanceEffectLabel.Text = 'Turbulance Effect';
            app.TurbulanceEffect = uieditfield(app.OutputTab, 'numeric');
            app.TurbulanceEffectdB = uilabel(app.OutputTab);
            app.TurbulanceEffectdB.Text = 'dB';
            app.PointingErrorsLabel = uilabel(app.OutputTab);
            app.PointingErrorsLabel.Text = 'Pointing Errors';
            app.PointingErrors = uieditfield(app.OutputTab, 'numeric');
            app.PointingErrorsdB = uilabel(app.OutputTab);
            app.PointingErrorsdB.Text = 'dB';
            app.ReceiverLabel = uilabel(app.OutputTab);
            app.ReceiverLabel.Text = 'Receiver';
            app.ReceivedbackEditFieldLabel = uilabel(app.OutputTab);
            app.ReceivedbackEditFieldLabel.Text = 'Received back';
            app.Receivedback = uieditfield(app.OutputTab, 'text');
            app.Receivedback.Value = 'Input message to transmit in input tab';
            app.BEREditFieldLabel = uilabel(app.OutputTab);
            app.BEREditFieldLabel.Text = 'BER';
            app.BER = uieditfield(app.OutputTab, 'numeric');
            app.SNREditFieldLabel = uilabel(app.OutputTab);
            app.SNREditFieldLabel.Text = 'SNR (dB)';
            app.SNR = uieditfield(app.OutputTab, 'numeric');
            app.SNREditFieldLabel_2 = uilabel(app.OutputTab);
            app.SNREditFieldLabel_2.Text = 'Quality Factor';
            app.QualityFactor = uieditfield(app.OutputTab, 'numeric');
            app.BacktotransmitterButton = uibutton(app.OutputTab, 'push');
            app.BacktotransmitterButton.ButtonPushedFcn = createCallbackFcn(app, @BacktotransmitterButtonPushed, true);
            app.BacktotransmitterButton.Text = 'Back to transmitter';
            app.TabGroup3 = uitabgroup(app.OutputTab);
            app.BERvsSNRTab = uitab(app.TabGroup3);
            app.BERvsSNRTab.Title = 'BER vs SNR';
            app.BERvsSNR = uiaxes(app.BERvsSNRTab);
            title(app.BERvsSNR, 'Title')
            xlabel(app.BERvsSNR, 'X')
            ylabel(app.BERvsSNR, 'Y')
            zlabel(app.BERvsSNR, 'Z')
            app.BERvsQTab = uitab(app.TabGroup3);
            app.BERvsQTab.Title = 'BER vs Q';
            app.BERvsQ = uiaxes(app.BERvsQTab);
            title(app.BERvsQ, 'Title')
            xlabel(app.BERvsQ, 'X')
            ylabel(app.BERvsQ, 'Y')
            zlabel(app.BERvsQ, 'Z')
            app.CapacityvsBandwidthTab = uitab(app.TabGroup3);
            app.CapacityvsBandwidthTab.Title = 'Capacity vs Bandwidth';
            app.CAPACITYvsSNR = uiaxes(app.CapacityvsBandwidthTab);
            title(app.CAPACITYvsSNR, 'Title')
            xlabel(app.CAPACITYvsSNR, 'X')
            ylabel(app.CAPACITYvsSNR, 'Y')
            zlabel(app.CAPACITYvsSNR, 'Z')
            % Show the figure after all components are created
            app.FSOCSystemUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FreeSpaceOpticalCommunicationsApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FSOCSystemUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FSOCSystemUIFigure)
        end
    end
end